classdef appMaquinaPVI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        AMMNPVIUIFigure      matlab.ui.Figure
        GridLayout           matlab.ui.container.GridLayout
        LeftPanel            matlab.ui.container.Panel
        Lamp                 matlab.ui.control.Lamp
        ErroLampLabel_2      matlab.ui.control.Label
        Switch               matlab.ui.control.Switch
        ddMetodo             matlab.ui.control.DropDown
        MetodoDropDownLabel  matlab.ui.control.Label
        bAtualizar           matlab.ui.control.Button
        DadosPVI             matlab.ui.container.Panel
        eY0                  matlab.ui.control.NumericEditField
        y0EditFieldLabel     matlab.ui.control.Label
        eN                   matlab.ui.control.NumericEditField
        nEditFieldLabel      matlab.ui.control.Label
        eB                   matlab.ui.control.NumericEditField
        bEditFieldLabel      matlab.ui.control.Label
        eA                   matlab.ui.control.NumericEditField
        aEditFieldLabel      matlab.ui.control.Label
        eF                   matlab.ui.control.EditField
        ftyLabel             matlab.ui.control.Label
        RightPanel           matlab.ui.container.Panel
        UITabela             matlab.ui.control.Table
        UIFormulas           matlab.ui.control.UIAxes
        UIGrafico            matlab.ui.control.UIAxes
        FicheiroMenu         matlab.ui.container.Menu
        mRelatrio            matlab.ui.container.Menu
        mAutores             matlab.ui.container.Menu
        mSair                matlab.ui.container.Menu
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            bAtualizarButtonPushed(app,[])
            app.UIFormulas.XTick = [];
            app.UIFormulas.YTick = [];
            app.UIFormulas.Box = 'on';
            app.UIFormulas.Toolbar.Visible = 'off';
        end

        % Button pushed function: bAtualizar
        function bAtualizarButtonPushed(app, event)
            syms y(t)
            strF = app.eF.Value;
            f =@(t,y) eval(vectorize(strF));
            a = app.eA.Value;
            b = app.eB.Value;   
            n = app.eN.Value;           
            y0 = app.eY0.Value;
            
            testeF = MException('Matlab:BadFunction','Introduza uma função de t e y!');


            try
                try
                    app.Lamp.Color = 'g';
                    f(t,y);
                catch 
                    app.Lamp.Color = 'r';
                    throw(testeF);
                end
                
                sExata = dsolve(diff(y,t)==f(t,y),y(a)==y0);
    
                g =@(t) eval(vectorize(char(sExata)));
                
                h = (b-a)/n;
                t = a:h:b;
                yExata = g(t);
                
                plot(app.UIGrafico,t,yExata,"-ro")
                hold(app.UIGrafico,"on")
                legend(app.UIGrafico,'Exata') 
                
                metodo = app.ddMetodo.Value;
                
               switch metodo
                   
                   case 'Euler'
                        yEuler = NEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        tabela = [t.',yExata.',yEuler.',erroEuler.'];
                        NomeColunas = {'t','Exata','Euler','erroEuler'};
                        plot(app.UIGrafico,t,yEuler,"-g*")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','Euler')
                      
                   case 'Euler Melhorado'
                        yEulerM = NEulerM(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        tabela = [t.',yExata.',yEulerM.',erroEulerM.'];
                        NomeColunas = {'t','Exata','EulerM','erroEulerM'};
                        plot(app.UIGrafico,t,yEulerM,"-m*")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','EulerM')
                        
                   case 'Euler/Euler Melhorado'
                        yEuler = NEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        yEulerM = NEulerM(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        tabela = [t.',yExata.',yEuler.',yEulerM.',erroEuler.',erroEulerM.'];
                        NomeColunas = {'t','Exata','Euler','EulerM','erroEuler','erroEulerM'};
                        plot(app.UIGrafico,t,yEuler,"-g*") 
                        plot(app.UIGrafico,t,yEulerM,"-mo")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','Euler','EulerM')
                       
                   case 'RK2'
                        yRK2 = NRK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        tabela = [t.',yExata.',yRK2.',erroRK2.'];
                        NomeColunas = {'t','Exata','RK2','erroRK2'};
                        plot(app.UIGrafico,t,yRK2,"-y*")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','RK2')
                        
                   case 'Euler/RK2'
                        yEuler = NEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        yRK2 = NRK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        tabela = [t.',yExata.',yEuler.',yRK2.',erroEuler.',erroRK2.'];
                        NomeColunas = {'t','Exata','Euler','RK2','erroEuler','erroRK2'};
                        plot(app.UIGrafico,t,yEuler,"-g*") 
                        plot(app.UIGrafico,t,yRK2,"-mo")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','Euler','RK2')
                        
                   case 'RK4'
                        yRK4 = NRK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        tabela = [t.',yExata.',yRK4.',erroRK4.'];
                        NomeColunas = {'t','Exata','RK4','erroRK4'};
                        plot(app.UIGrafico,t,yRK4,"-b*")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','RK4')
                        
                   case 'RK2/RK4'
                        yRK2 = NRK4(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        yRK4 = NRK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        tabela = [t.',yExata.',yRK2.',yRK4.',erroRK2.',erroRK4.'];
                        NomeColunas = {'t','Exata','RK2','RK4','erroRK2','erroRK4'};
                        plot(app.UIGrafico,t,yRK2,"-y*") 
                        plot(app.UIGrafico,t,yRK4,"-b*")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','RK2','RK4')
                        
                   case 'ODE45'
                        yODE45 = NODE45(f,a,b,n,y0);
                        erroODE45 = abs(yExata-yODE45);
                        tabela = [t.',yExata.',yODE45.',erroODE45.'];
                        NomeColunas = {'t','Exata','ODE45','erroODE45'};
                        plot(app.UIGrafico,t,yODE45,"-gx")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','ODE45')
                       
                   case 'Adams'
                        yAdams = NAdams(f,a,b,n,y0);
                        erroAdams = abs(yExata-yAdams);
                        tabela = [t.',yExata.',yAdams.',erroAdams.'];
                        NomeColunas = {'t','Exata','Adams','erroAdams'};
                        plot(app.UIGrafico,t,yAdams,"--kx")
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','Adams')
                       
                   case 'Todos'
                        yEuler = NEuler(f,a,b,n,y0);
                        erroEuler = abs(yExata-yEuler);
                        yEulerM = NEulerM(f,a,b,n,y0);
                        erroEulerM = abs(yExata-yEulerM);
                        yRK2 = NRK2(f,a,b,n,y0);
                        erroRK2 = abs(yExata-yRK2);
                        yRK4 = NRK4(f,a,b,n,y0);
                        erroRK4 = abs(yExata-yRK4);
                        yODE45 = NODE45(f,a,b,n,y0);
                        erroODE45 = abs(yExata-yODE45);
                        yAdams = NAdams(f,a,b,n,y0);
                        erroAdams = abs(yExata-yAdams);
                        
                        tabela = [t.',yExata.',yEuler.',yEulerM.',yRK2.',yRK4.',yODE45.',yAdams.',...
                            erroEuler.',erroEulerM.',erroRK2.',erroRK4.',erroODE45.',erroAdams.'];
                        NomeColunas = {'t','Exata','Euler','EulerM','RK2','RK4','ODE45','Adams',...
                            'erroEuler','erroEulerM','erroRK2','erroRK4','erroODE45','erroAdams'};
                        plot(app.UIGrafico,t,yEuler,"-g*") 
                        plot(app.UIGrafico,t,yEulerM,"-mo") 
                        plot(app.UIGrafico,t,yRK2,"-y*")  
                        plot(app.UIGrafico,t,yRK4,"-b*")  
                        plot(app.UIGrafico,t,yODE45,"-gx")  
                        plot(app.UIGrafico,t,yAdams,"--kx")
                        
                        hold(app.UIGrafico,"off")
                        legend(app.UIGrafico,'Exata','Euler','EulerM','RK2','RK4','ODE45','Adams')
                        
                       
               end
                        T = array2table(tabela);
                        app.UITabela.Data = T;
                        app.UITabela.ColumnName = NomeColunas;
               
            catch ME
                errordlg(ME.message,"Erro","modal")
            end
           
        end

        % Value changed function: Switch
        function SwitchValueChanged(app, event)
            value = app.Switch.Value;
            
            if value == "On"
                grid(app.UIGrafico,"on")
            else
                grid(app.UIGrafico,"off")
            end
        end

        % Value changed function: ddMetodo
        function ddMetodoValueChanged(app, event)
            value = app.ddMetodo.Value;
            
            if value == "Euler"
                formula = str2sym("y(i+1)=y(i)+hf(t(i),y(i))");
                
                
            elseif value == "Euler Melhorado"
                formula = str2sym("y(i+1) = y(i)+ (h/2)*(f(t(i),y(i))+f(t(i+1),y(i)+h*f(t(i),y(i))))");
               
            
            elseif value == "RK2"
                formula = str2sym("y(i+1) = y(i)+1/2*(k1 + k2)");
               
            
            elseif value == "RK4"
                formula = str2sym("y(i+1) = y(i)+1/6*(k1+(2*k2)+(2*k3)+k4)");
                
            
            elseif value == "Adams"
                formula = str2sym("y(i+1)=y(i)+ (h/24) *(55*f(i) - 59*f(i-1) + 37*f(i-2) - 9*f(i-3))");
                
            elseif value == "ODE45"
                formula = str2sym("y = ODE45(f,t,y0)");
            else
                formula = str2sym("");
                
            end
            
            formulaLatex = ['$',latex(formula),'$'];
            cla(app.UIFormulas,"reset");
            text(app.UIFormulas,0.1,0.5,formulaLatex,'Interpreter',"latex","FontSize",12)


            app.UIFormulas.XTick = [];
            app.UIFormulas.YTick = [];
            app.UIFormulas.Box = 'on';
        end

        % Menu selected function: mSair
        function mSairMenuSelected(app, event)
            SN = questdlg('Quer mesmo sair?','Sair','Sim','Não','Não');
            
            if strcmp(SN,'Sim')
                delete(app.AMMNPVIUIFigure)
            end
        end

        % Menu selected function: mRelatrio
        function mRelatrioSelected(app, event)
            open ("Relatorio_AT01_AM2.pdf")
        end

        % Callback function
        function AutoresMenuSelected(app, event)
            
        end

        % Menu selected function: mAutores
        function mAutoresMenuSelected(app, event)
            run("appAutores.mlapp")
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.AMMNPVIUIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {519, 519};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {261, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.AMMNPVIUIFigure = uifigure('Visible', 'off');
            app.AMMNPVIUIFigure.Name = 'AM MN-PVI';
            app.AMMNPVIUIFigure.Icon = 'Sem nome.png';
            app.AMMNPVIUIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.AMMNPVIUIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DadosPVI = uipanel(app.LeftPanel);
            app.DadosPVI.Title = 'Dados do PVI';
            app.ftyLabel = uilabel(app.DadosPVI);
            app.ftyLabel.Text = 'f(t,y) = ';
            app.eF = uieditfield(app.DadosPVI, 'text');
            app.eF.Value = '-2*t*y';
            app.aEditFieldLabel = uilabel(app.DadosPVI);
            app.aEditFieldLabel.Text = 'a =';
            app.eA = uieditfield(app.DadosPVI, 'numeric');
            app.eA.Value = 1;
            app.bEditFieldLabel = uilabel(app.DadosPVI);
            app.bEditFieldLabel.Text = 'b =';
            app.eB = uieditfield(app.DadosPVI, 'numeric');
            app.eB.Value = 1.5;
            app.nEditFieldLabel = uilabel(app.DadosPVI);
            app.nEditFieldLabel.Text = 'n =';
            app.eN = uieditfield(app.DadosPVI, 'numeric');
            app.eN.Value = 10;
            app.y0EditFieldLabel = uilabel(app.DadosPVI);
            app.y0EditFieldLabel.Text = 'y0 =';
            app.eY0 = uieditfield(app.DadosPVI, 'numeric');
            app.eY0.Value = 2;
            app.bAtualizar = uibutton(app.LeftPanel, 'push');
            app.bAtualizar.ButtonPushedFcn = createCallbackFcn(app, @bAtualizarButtonPushed, true);
            app.bAtualizar.Text = 'Atualizar';
            app.MetodoDropDownLabel = uilabel(app.LeftPanel);
            app.MetodoDropDownLabel.Text = 'Metodo';
            app.ddMetodo = uidropdown(app.LeftPanel);
            app.ddMetodo.Items = {'Euler', 'Euler Melhorado', 'Euler/Euler Melhorado', 'RK2', 'Euler/RK2', 'RK4', 'RK2/RK4', 'ODE45', 'Adams', 'Todos'};
            app.ddMetodo.ValueChangedFcn = createCallbackFcn(app, @ddMetodoValueChanged, true);
            app.ddMetodo.Value = 'Euler';
            app.Switch = uiswitch(app.LeftPanel, 'slider');
            app.Switch.ValueChangedFcn = createCallbackFcn(app, @SwitchValueChanged, true);
            app.ErroLampLabel_2 = uilabel(app.LeftPanel);
            app.ErroLampLabel_2.Text = 'Erro:';
            app.Lamp = uilamp(app.LeftPanel);
            app.RightPanel = uipanel(app.GridLayout);
            app.UIGrafico = uiaxes(app.RightPanel);
            app.UIFormulas = uiaxes(app.RightPanel);
            app.UITabela = uitable(app.RightPanel);
            app.UITabela.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITabela.RowName = {};
            app.FicheiroMenu = uimenu(app.AMMNPVIUIFigure);
            app.FicheiroMenu.Text = 'Ficheiro';
            app.mRelatrio = uimenu(app.FicheiroMenu);
            app.mRelatrio.MenuSelectedFcn = createCallbackFcn(app, @mRelatrioSelected, true);
            app.mRelatrio.Text = 'Relatório';
            app.mAutores = uimenu(app.FicheiroMenu);
            app.mAutores.MenuSelectedFcn = createCallbackFcn(app, @mAutoresMenuSelected, true);
            app.mAutores.Text = 'Autores';
            app.mSair = uimenu(app.FicheiroMenu);
            app.mSair.MenuSelectedFcn = createCallbackFcn(app, @mSairMenuSelected, true);
            app.mSair.Text = 'Sair';
            % Show the figure after all components are created
            app.AMMNPVIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = appMaquinaPVI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.AMMNPVIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.AMMNPVIUIFigure)
        end
    end
end