classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CRRedeNeuronalUIFigure          matlab.ui.Figure
        TabGroup                        matlab.ui.container.TabGroup
        ClassificaTab                   matlab.ui.container.Tab
        Panel_2                         matlab.ui.container.Panel
        ClassificaesLabel               matlab.ui.control.Label
        triaEField                      matlab.ui.control.NumericEditField
        triangleEditFieldLabel          matlab.ui.control.Label
        tEField                         matlab.ui.control.NumericEditField
        trapezoidEditFieldLabel         matlab.ui.control.Label
        sEField                         matlab.ui.control.NumericEditField
        squareEditFieldLabel            matlab.ui.control.Label
        pEField                         matlab.ui.control.NumericEditField
        parallelogramEditFieldLabel     matlab.ui.control.Label
        kEField                         matlab.ui.control.NumericEditField
        kiteEditFieldLabel              matlab.ui.control.Label
        cEField                         matlab.ui.control.NumericEditField
        circleEditFieldLabel            matlab.ui.control.Label
        ResultadoEditField              matlab.ui.control.EditField
        ResultadoLabel                  matlab.ui.control.Label
        RedeNeuronalClassificaoLabel    matlab.ui.control.Label
        Panel                           matlab.ui.container.Panel
        FunodeTreinoEditField           matlab.ui.control.EditField
        FunodeTreinoEditFieldLabel      matlab.ui.control.Label
        FunodeAtivaoSaidaEditField      matlab.ui.control.EditField
        FunodeAtivaoSaidaEditFieldLabel  matlab.ui.control.Label
        FunodeAtivaoEditField_6         matlab.ui.control.EditField
        FunodeAtivaoEditField_6Label    matlab.ui.control.Label
        FunodeAtivaoEditField_5         matlab.ui.control.EditField
        FunodeAtivaoEditField_5Label    matlab.ui.control.Label
        ClassificarRedeExternaButton    matlab.ui.control.Button
        RedeEditField                   matlab.ui.control.EditField
        RedeEditFieldLabel              matlab.ui.control.Label
        ProcurarRedeExternaButton       matlab.ui.control.Button
        Label_5                         matlab.ui.control.Label
        ClassificarButton_3             matlab.ui.control.Button
        TestEditField_2                 matlab.ui.control.NumericEditField
        TestEditField_2Label            matlab.ui.control.Label
        ValEditField_2                  matlab.ui.control.NumericEditField
        ValEditField_2Label             matlab.ui.control.Label
        TrainEditField_2                matlab.ui.control.NumericEditField
        TrainEditField_2Label           matlab.ui.control.Label
        NmerodeNeurniosEditField_5      matlab.ui.control.NumericEditField
        NmerodeNeurniosEditField_5Label  matlab.ui.control.Label
        Camada1Label                    matlab.ui.control.Label
        NmerodeNeurniosEditField_4      matlab.ui.control.NumericEditField
        NmerodeNeurniosEditField_4Label  matlab.ui.control.Label
        Camada2Label                    matlab.ui.control.Label
        ClassificarButton               matlab.ui.control.Button
        ClassificaocomRedeNeuronalExternaLabel  matlab.ui.control.Label
        ImagemaClassificarLabel         matlab.ui.control.Label
        ClassificaocomRedeNeuronalLocalLabel  matlab.ui.control.Label
        ProcurarImagemButton            matlab.ui.control.Button
        ImagemEditField                 matlab.ui.control.EditField
        ImagemaclassificarLabel         matlab.ui.control.Label
        Label_3                         matlab.ui.control.Label
        Label_4                         matlab.ui.control.Label
        DatasetTab                      matlab.ui.container.Tab
        Panel_4                         matlab.ui.container.Panel
        PrecisoEditField                matlab.ui.control.NumericEditField
        PrecisoEditFieldLabel           matlab.ui.control.Label
        ResultadoLabel_2                matlab.ui.control.Label
        Panel_3                         matlab.ui.container.Panel
        FunodeTreinoEditField_2         matlab.ui.control.EditField
        FunodeTreinoEditField_2Label    matlab.ui.control.Label
        FunodeAtivaoSaidaEditField_2    matlab.ui.control.EditField
        FunodeAtivaoSaidaEditField_2Label  matlab.ui.control.Label
        FunodeAtivaoEditField_8         matlab.ui.control.EditField
        FunodeAtivaoEditField_8Label    matlab.ui.control.Label
        FunodeAtivaoEditField_7         matlab.ui.control.EditField
        FunodeAtivaoEditField_7Label    matlab.ui.control.Label
        ClassificarRedeExternaButton_2  matlab.ui.control.Button
        RedeEditField_2                 matlab.ui.control.EditField
        RedeEditField_2Label            matlab.ui.control.Label
        ProcurarRedeExternaButton_2     matlab.ui.control.Button
        Label_8                         matlab.ui.control.Label
        ClassificarButton_5             matlab.ui.control.Button
        TestEditField_3                 matlab.ui.control.NumericEditField
        TestEditField_3Label            matlab.ui.control.Label
        ValEditField_3                  matlab.ui.control.NumericEditField
        ValEditField_3Label             matlab.ui.control.Label
        TrainEditField_3                matlab.ui.control.NumericEditField
        TrainEditField_3Label           matlab.ui.control.Label
        NmerodeNeurniosEditField_7      matlab.ui.control.NumericEditField
        NmerodeNeurniosEditField_7Label  matlab.ui.control.Label
        Camada1Label_2                  matlab.ui.control.Label
        NmerodeNeurniosEditField_6      matlab.ui.control.NumericEditField
        NmerodeNeurniosEditField_6Label  matlab.ui.control.Label
        Camada2Label_2                  matlab.ui.control.Label
        ClassificarButton_4             matlab.ui.control.Button
        ClassificaocomRedeNeuronalExternaLabel_2  matlab.ui.control.Label
        DatasetaClassificarLabel        matlab.ui.control.Label
        ClassificaocomRedeNeuronalLocalLabel_2  matlab.ui.control.Label
        ProcurarImagemButton_2          matlab.ui.control.Button
        DatasetEditField                matlab.ui.control.EditField
        DatasetEditFieldLabel           matlab.ui.control.Label
        Label_7                         matlab.ui.control.Label
        Label_6                         matlab.ui.control.Label
        RedeNeuronalClassificaodedatasetprecisoLabel  matlab.ui.control.Label
        TreinarTab                      matlab.ui.container.Tab
        RedeNeuronalLabel               matlab.ui.control.Label
        CriarTreinarRedeButton          matlab.ui.control.Button
        DatasetdeentradaPanel           matlab.ui.container.Panel
        ProcurarButton                  matlab.ui.control.Button
        PastadoDataSetEditField         matlab.ui.control.EditField
        PastadoDataSetLabel             matlab.ui.control.Label
        Separador                       matlab.ui.container.Panel
        Label_2                         matlab.ui.control.Label
        FunodetreinoButtonGroup         matlab.ui.control.DropDown
        FunodeDivisoLabel               matlab.ui.control.Label
        AtribuircamadaButton            matlab.ui.control.Button
        TestEditField                   matlab.ui.control.NumericEditField
        TestEditFieldLabel              matlab.ui.control.Label
        ValEditField                    matlab.ui.control.NumericEditField
        ValEditFieldLabel               matlab.ui.control.Label
        TrainEditField                  matlab.ui.control.NumericEditField
        TrainEditFieldLabel             matlab.ui.control.Label
        FunodeAtivaoSaidaDropDown       matlab.ui.control.DropDown
        FunodeAtivaoSaidaLabel          matlab.ui.control.Label
        NmerodeNeurniosEditField        matlab.ui.control.NumericEditField
        NmerodeNeurniosEditFieldLabel   matlab.ui.control.Label
        CamadaDropDown                  matlab.ui.control.DropDown
        CamadaDropDownLabel             matlab.ui.control.Label
        NmerodeCamadasEditField         matlab.ui.control.NumericEditField
        NmerodeCamadasEditFieldLabel    matlab.ui.control.Label
        FunodeAtivaoDropDown            matlab.ui.control.DropDown
        FunodeAtivaoDropDownLabel       matlab.ui.control.Label
        AutoresTab                      matlab.ui.container.Tab
        MiguelNeves2020146521Label      matlab.ui.control.Label
        NunoDomingues2020109910Label    matlab.ui.control.Label
        Image                           matlab.ui.control.Image
    end


    properties (Access = private)
        layerStruct = struct('ID',{},'Neuronios',{},'fAtiv',{}); %,'fAtivOut',{},'fDiv',{},'train',{},'val',{},'test',{}
        arrayStruct = [];
        DirPathDataSet;
        DirPathDataSerClas;
        DirPathImage;
        DirPathNeuralNetwork;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            NmerodeCamadasEditFieldValueChanged(app, 0);
        end

        % Value changed function: NmerodeCamadasEditField
        function NmerodeCamadasEditFieldValueChanged(app, event)
            value = app.NmerodeCamadasEditField.Value;

            app.CamadaDropDown.Items = {};

            if value == 0
                app.CamadaDropDown.Enable = 'off';
                app.NmerodeNeurniosEditField.Enable = 'off';
                app.FunodeAtivaoDropDown.Enable = 'off';
                app.FunodeAtivaoSaidaDropDown.Enable = 'off';
                app.FunodetreinoButtonGroup.Enable = 'off';
                app.TrainEditField.Enable = 'off';
                app.ValEditField.Enable = 'off';
                app.TestEditField.Enable = 'off';
            else
                app.CamadaDropDown.Enable = 'on';
                app.NmerodeNeurniosEditField.Enable = 'on';
                app.FunodeAtivaoDropDown.Enable = 'on';
                app.FunodeAtivaoSaidaDropDown.Enable = 'on';
                app.FunodetreinoButtonGroup.Enable = 'on';
                app.TrainEditField.Enable = 'on';
                app.ValEditField.Enable = 'on';
                app.TestEditField.Enable = 'on';

                for i=1 : value
                    app.CamadaDropDown.Items = [app.CamadaDropDown.Items num2str(i)];
                end
            end


        end

        % Value changed function: CamadaDropDown
        function CamadaDropDownValueChanged(app, event)

        end

        % Button pushed function: AtribuircamadaButton
        function AtribuircamadaButtonPushed(app, event)
            if app.NmerodeCamadasEditField.Value == 0
                warndlg('Insira pelo menos uma camada.','Warning');
                return;
            end
            value = app.CamadaDropDown.Value;
            
            app.layerStruct(value).ID = value;
            app.layerStruct(value).Neuronios = app.NmerodeNeurniosEditField.Value;
            app.layerStruct(value).fAtiv = app.FunodeAtivaoDropDown.Value;
%             app.layerStruct(value).fAtivOut = app.FunodeAtivaoSaidaDropDown.Value;
%             app.layerStruct(value).fDiv = app.FunodetreinoButtonGroup.Value;
%             app.layerStruct(value).train = app.TrainEditField.Value;
%             app.layerStruct(value).val = app.ValEditField.Value;
%             app.layerStruct(value).test = app.TestEditField.Value;
            
            CheckID = 0;
            for i = 1:length(app.arrayStruct)
                if app.arrayStruct(i).ID == value
                    app.arrayStruct(i).Neuronios = app.NmerodeNeurniosEditField.Value;
                    app.arrayStruct(i).fAtiv = app.FunodeAtivaoDropDown.Value;
%                     app.arrayStruct(i).fAtivOut = app.FunodeAtivaoSaidaDropDown.Value;
%                     app.arrayStruct(i).fDiv = app.FunodetreinoButtonGroup.Value;
%                     app.arrayStruct(i).train = app.TrainEditField.Value;
%                     app.arrayStruct(i).val = app.ValEditField.Value;
%                     app.arrayStruct(i).test = app.TestEditField.Value;

                    CheckID = 1;
                end
            end
            if CheckID == 0
                app.arrayStruct = [app.arrayStruct, app.layerStruct(value)];
            end
           
%             fprintf("DEBUG IMPRIME\n\n");
%             for i = 1:length(app.arrayStruct)
%                 disp(app.arrayStruct(i))
%             end
        end

        % Button pushed function: CriarTreinarRedeButton
        function CriarTreinarRedeButtonPushed(app, event)
            %fprintf("DEBUG  ARRAY %f NCAMADAS %f\n", length(app.arrayStruct), app.NmerodeCamadasEditField.Value);
            if app.NmerodeCamadasEditField.Value == 0
                warndlg('Insira pelo menos uma camada.','Warning');
                return;
            end
            if length(app.arrayStruct) ~= app.NmerodeCamadasEditField.Value
                warndlg('Preencha as todas camadas!','Warning');
                return;
            end
            %fprintf("\n[************ DEBUG CriarButton ************]\n [DirPath (%s)]\n\n", app.DirPath);
            if isempty(app.DirPathDataSet)
                warndlg(sprintf('Insira um DataSet!\n(circle, kite, parallelogram, square, trapezoid, triangle)'),'Warning');
                return;
            end
            trainNeuralNetwork(app.DirPathDataSet, app.arrayStruct, app.FunodeAtivaoSaidaDropDown.Value, app.FunodetreinoButtonGroup.Value, ...
                app.TrainEditField.Value, app.ValEditField.Value, app.TestEditField.Value);
        end

        % Button pushed function: ProcurarButton
        function ProcurarButtonPushed(app, event)
          app.DirPathDataSet = uigetdir('C:\');

          if ~isequal(app.DirPathDataSet, 0)
            app.PastadoDataSetEditField.Value = app.DirPathDataSet;
          end
        end

        % Callback function
        function UIAxesButtonDown(app, event)

        end

        % Button pushed function: ProcurarImagemButton
        function ProcurarImagemButtonPushed(app, event)
            while 1
                [file, path] = uigetfile('*.png');
                %if ~isequal(file, 0) || ~isequal(path, 0)
                    if isequal(file, 0) || isequal(path, 0)
                        return;
                    end
                    [~, ~, fType] = fileparts(file);
                    if lower(fType) ~= ".png"
                        warndlg("Selecione uma imagem com formato '.png'.", 'Warning');
                        return;
                    end
                    FileDirectory = strcat(path, file);
                    %fprintf("[DEBUG] Directory [ %s ]\n", FileDirectory);
                    break;
                %end
            end
            app.DirPathImage = FileDirectory;
            app.ImagemEditField.Value = app.DirPathImage;
        end

        % Button pushed function: ProcurarRedeExternaButton
        function ProcurarRedeExternaButtonPushed(app, event)
            while 1
                [file, path] = uigetfile('*.mat');
                if ~isequal(file, 0) || ~isequal(path, 0)
                    [~, ~, fType] = fileparts(file);
                    if lower(fType) ~= ".mat"
                        warndlg("Selecione uma rede com formato '.mat'.", 'Warning');
                        return;
                    end
                    FileDirectory = strcat(path, file);
                    %fprintf("[DEBUG] Directory [ %s ]\n", FileDirectory);
                    break;
                end
            end
            app.DirPathNeuralNetwork = FileDirectory;
            app.RedeEditField.Value = app.DirPathNeuralNetwork;
        end

        % Button pushed function: ClassificarButton
        function ClassificarButtonPushed(app, event)
            if isempty(app.ImagemEditField.Value)
                warndlg("Escolha uma imagem.", 'Warning');
                return;
            end
            [result,clas] = classifyImage('Train10.mat', app.ImagemEditField.Value);
            set(app.ResultadoEditField, 'BackgroundColor', [0.47 0.67 0.19]);
            app.ResultadoEditField.Value = result;
            pause(0.5);
            set(app.ResultadoEditField, 'BackgroundColor', 'white');
            app.cEField.Value = round(clas(1),2);
            app.kEField.Value = round(clas(2),2);
            app.pEField.Value = round(clas(3),2);
            app.sEField.Value = round(clas(4),2);
            app.tEField.Value = round(clas(5),2);
            app.triaEField.Value = round(clas(6),2);
        end

        % Button pushed function: ClassificarRedeExternaButton
        function ClassificarRedeExternaButtonPushed(app, event)
            if isempty(app.RedeEditField.Value)
                warndlg("Selecione uma rede.", 'Warning');
                return;
            end
            [result,clas] = classifyImage(app.RedeEditField.Value, app.ImagemEditField.Value);
            set(app.ResultadoEditField, 'BackgroundColor', [0.47 0.67 0.19]);
            app.ResultadoEditField.Value = result;
            pause(0.5);
            set(app.ResultadoEditField, 'BackgroundColor', 'white');
            app.cEField.Value = round(clas(1),2);
            app.kEField.Value = round(clas(2),2);
            app.pEField.Value = round(clas(3),2);
            app.sEField.Value = round(clas(4),2);
            app.tEField.Value = round(clas(5),2);
            app.triaEField.Value = round(clas(6),2);
        end

        % Button pushed function: ProcurarImagemButton_2
        function ProcurarImagemButton_2Pushed(app, event)

          app.DirPathDataSerClas = uigetdir('C:\');
          if ~isequal(app.DirPathDataSerClas, 0)
            app.DatasetEditField.Value = app.DirPathDataSerClas;
          end
        end

        % Button pushed function: ProcurarRedeExternaButton_2
        function ProcurarRedeExternaButton_2Pushed(app, event)
            while 1
                [file, path] = uigetfile('*.mat');
                if ~isequal(file, 0) || ~isequal(path, 0)
                    [~, ~, fType] = fileparts(file);
                    if lower(fType) ~= ".mat"
                        warndlg("Selecione uma rede com formato '.mat'.", 'Warning');
                        return;
                    end
                    FileDirectory = strcat(path, file);
                    %fprintf("[DEBUG] Directory [ %s ]\n", FileDirectory);
                    break;
                end
            end
            app.DirPathNeuralNetwork = FileDirectory;
            app.RedeEditField_2.Value = app.DirPathNeuralNetwork;
        end

        % Button pushed function: ClassificarButton_4
        function ClassificarButton_4Pushed(app, event)
            if isempty(app.DatasetEditField.Value)
                warndlg("Escolha um dataset.", 'Warning');
                return;
            end
            
            result = testImagesGUI('Train10.mat',app.DatasetEditField.Value);
            app.PrecisoEditField.Value = result;        
            
        end

        % Button pushed function: ClassificarRedeExternaButton_2
        function ClassificarRedeExternaButton_2Pushed(app, event)
            if isempty(app.DatasetEditField.Value)
                warndlg("Escolha um dataset.", 'Warning');
                return;
            end
            if isempty(app.RedeEditField_2.Value)
                warndlg("Selecione uma rede.", 'Warning');
                return;
            end
            result = testImagesGUI(app.RedeEditField_2.Value,app.DatasetEditField.Value);
            app.PrecisoEditField.Value = result;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CRRedeNeuronalUIFigure = uifigure('Visible', 'off');
            app.CRRedeNeuronalUIFigure.Name = 'CR - Rede Neuronal';
            app.TabGroup = uitabgroup(app.CRRedeNeuronalUIFigure);
            app.ClassificaTab = uitab(app.TabGroup);
            app.ClassificaTab.Title = 'Classifica';
            app.Panel = uipanel(app.ClassificaTab);
            app.Label_4 = uilabel(app.Panel);
            app.Label_4.Text = '';
            app.Label_3 = uilabel(app.Panel);
            app.Label_3.Text = '';
            app.ImagemaclassificarLabel = uilabel(app.Panel);
            app.ImagemaclassificarLabel.Text = 'Imagem:';
            app.ImagemEditField = uieditfield(app.Panel, 'text');
            app.ImagemEditField.Editable = 'off';
            app.ProcurarImagemButton = uibutton(app.Panel, 'push');
            app.ProcurarImagemButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarImagemButtonPushed, true);
            app.ProcurarImagemButton.Text = 'Procurar';
            app.ClassificaocomRedeNeuronalLocalLabel = uilabel(app.Panel);
            app.ClassificaocomRedeNeuronalLocalLabel.Text = ' Classificação com Rede Neuronal Local';
            app.ImagemaClassificarLabel = uilabel(app.Panel);
            app.ImagemaClassificarLabel.Text = '  Imagem a Classificar';
            app.ClassificaocomRedeNeuronalExternaLabel = uilabel(app.Panel);
            app.ClassificaocomRedeNeuronalExternaLabel.Text = ' Classificação com Rede Neuronal Externa';
            app.ClassificarButton = uibutton(app.Panel, 'push');
            app.ClassificarButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButtonPushed, true);
            app.ClassificarButton.Text = 'Classificar';
            app.Camada2Label = uilabel(app.Panel);
            app.Camada2Label.Text = 'Camada 2';
            app.NmerodeNeurniosEditField_4Label = uilabel(app.Panel);
            app.NmerodeNeurniosEditField_4Label.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField_4 = uieditfield(app.Panel, 'numeric');
            app.NmerodeNeurniosEditField_4.Editable = 'off';
            app.NmerodeNeurniosEditField_4.Value = 10;
            app.Camada1Label = uilabel(app.Panel);
            app.Camada1Label.Text = 'Camada 1';
            app.NmerodeNeurniosEditField_5Label = uilabel(app.Panel);
            app.NmerodeNeurniosEditField_5Label.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField_5 = uieditfield(app.Panel, 'numeric');
            app.NmerodeNeurniosEditField_5.Editable = 'off';
            app.NmerodeNeurniosEditField_5.Value = 10;
            app.TrainEditField_2Label = uilabel(app.Panel);
            app.TrainEditField_2Label.Text = 'Train';
            app.TrainEditField_2 = uieditfield(app.Panel, 'numeric');
            app.TrainEditField_2.Editable = 'off';
            app.TrainEditField_2.Value = 0.7;
            app.ValEditField_2Label = uilabel(app.Panel);
            app.ValEditField_2Label.Text = 'Val';
            app.ValEditField_2 = uieditfield(app.Panel, 'numeric');
            app.ValEditField_2.Editable = 'off';
            app.ValEditField_2.Value = 0.15;
            app.TestEditField_2Label = uilabel(app.Panel);
            app.TestEditField_2Label.Text = 'Test';
            app.TestEditField_2 = uieditfield(app.Panel, 'numeric');
            app.TestEditField_2.Editable = 'off';
            app.TestEditField_2.Value = 0.15;
            app.ClassificarButton_3 = uibutton(app.Panel, 'push');
            app.ClassificarButton_3.Text = 'Classificar';
            app.Label_5 = uilabel(app.Panel);
            app.Label_5.Text = '';
            app.ProcurarRedeExternaButton = uibutton(app.Panel, 'push');
            app.ProcurarRedeExternaButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarRedeExternaButtonPushed, true);
            app.ProcurarRedeExternaButton.Text = 'Procurar';
            app.RedeEditFieldLabel = uilabel(app.Panel);
            app.RedeEditFieldLabel.Text = 'Rede:';
            app.RedeEditField = uieditfield(app.Panel, 'text');
            app.RedeEditField.Editable = 'off';
            app.ClassificarRedeExternaButton = uibutton(app.Panel, 'push');
            app.ClassificarRedeExternaButton.ButtonPushedFcn = createCallbackFcn(app, @ClassificarRedeExternaButtonPushed, true);
            app.ClassificarRedeExternaButton.Text = 'Classificar';
            app.FunodeAtivaoEditField_5Label = uilabel(app.Panel);
            app.FunodeAtivaoEditField_5Label.Text = 'Função de Ativação';
            app.FunodeAtivaoEditField_5 = uieditfield(app.Panel, 'text');
            app.FunodeAtivaoEditField_5.Editable = 'off';
            app.FunodeAtivaoEditField_5.Value = 'logsig';
            app.FunodeAtivaoEditField_6Label = uilabel(app.Panel);
            app.FunodeAtivaoEditField_6Label.Text = 'Função de Ativação';
            app.FunodeAtivaoEditField_6 = uieditfield(app.Panel, 'text');
            app.FunodeAtivaoEditField_6.Editable = 'off';
            app.FunodeAtivaoEditField_6.Value = 'logsig';
            app.FunodeAtivaoSaidaEditFieldLabel = uilabel(app.Panel);
            app.FunodeAtivaoSaidaEditFieldLabel.Text = 'Função de Ativação Saida';
            app.FunodeAtivaoSaidaEditField = uieditfield(app.Panel, 'text');
            app.FunodeAtivaoSaidaEditField.Editable = 'off';
            app.FunodeAtivaoSaidaEditField.Value = 'tansig';
            app.FunodeTreinoEditFieldLabel = uilabel(app.Panel);
            app.FunodeTreinoEditFieldLabel.Text = 'Função de Treino';
            app.FunodeTreinoEditField = uieditfield(app.Panel, 'text');
            app.FunodeTreinoEditField.Editable = 'off';
            app.FunodeTreinoEditField.Value = 'trainlm';
            app.RedeNeuronalClassificaoLabel = uilabel(app.ClassificaTab);
            app.RedeNeuronalClassificaoLabel.Text = ' Rede Neuronal - Classificação';
            app.Panel_2 = uipanel(app.ClassificaTab);
            app.ResultadoLabel = uilabel(app.Panel_2);
            app.ResultadoLabel.Text = 'Resultado';
            app.ResultadoEditField = uieditfield(app.Panel_2, 'text');
            app.ResultadoEditField.Editable = 'off';
            app.circleEditFieldLabel = uilabel(app.Panel_2);
            app.circleEditFieldLabel.Text = 'circle';
            app.cEField = uieditfield(app.Panel_2, 'numeric');
            app.cEField.Editable = 'off';
            app.kiteEditFieldLabel = uilabel(app.Panel_2);
            app.kiteEditFieldLabel.Text = 'kite';
            app.kEField = uieditfield(app.Panel_2, 'numeric');
            app.kEField.Editable = 'off';
            app.parallelogramEditFieldLabel = uilabel(app.Panel_2);
            app.parallelogramEditFieldLabel.Text = 'parallelogram';
            app.pEField = uieditfield(app.Panel_2, 'numeric');
            app.pEField.Editable = 'off';
            app.squareEditFieldLabel = uilabel(app.Panel_2);
            app.squareEditFieldLabel.Text = 'square';
            app.sEField = uieditfield(app.Panel_2, 'numeric');
            app.sEField.Editable = 'off';
            app.trapezoidEditFieldLabel = uilabel(app.Panel_2);
            app.trapezoidEditFieldLabel.Text = 'trapezoid';
            app.tEField = uieditfield(app.Panel_2, 'numeric');
            app.tEField.Editable = 'off';
            app.triangleEditFieldLabel = uilabel(app.Panel_2);
            app.triangleEditFieldLabel.Text = 'triangle';
            app.triaEField = uieditfield(app.Panel_2, 'numeric');
            app.triaEField.Editable = 'off';
            app.ClassificaesLabel = uilabel(app.Panel_2);
            app.ClassificaesLabel.Text = 'Classificações:';
            app.DatasetTab = uitab(app.TabGroup);
            app.DatasetTab.Title = 'Dataset ';
            app.RedeNeuronalClassificaodedatasetprecisoLabel = uilabel(app.DatasetTab);
            app.RedeNeuronalClassificaodedatasetprecisoLabel.Text = ' Rede Neuronal - Classificação de dataset (precisão)';
            app.Panel_3 = uipanel(app.DatasetTab);
            app.Label_6 = uilabel(app.Panel_3);
            app.Label_6.Text = '';
            app.Label_7 = uilabel(app.Panel_3);
            app.Label_7.Text = '';
            app.DatasetEditFieldLabel = uilabel(app.Panel_3);
            app.DatasetEditFieldLabel.Text = 'Dataset:';
            app.DatasetEditField = uieditfield(app.Panel_3, 'text');
            app.DatasetEditField.Editable = 'off';
            app.ProcurarImagemButton_2 = uibutton(app.Panel_3, 'push');
            app.ProcurarImagemButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcurarImagemButton_2Pushed, true);
            app.ProcurarImagemButton_2.Text = 'Procurar';
            app.ClassificaocomRedeNeuronalLocalLabel_2 = uilabel(app.Panel_3);
            app.ClassificaocomRedeNeuronalLocalLabel_2.Text = ' Classificação com Rede Neuronal Local';
            app.DatasetaClassificarLabel = uilabel(app.Panel_3);
            app.DatasetaClassificarLabel.Text = 'Dataset a Classificar';
            app.ClassificaocomRedeNeuronalExternaLabel_2 = uilabel(app.Panel_3);
            app.ClassificaocomRedeNeuronalExternaLabel_2.Text = ' Classificação com Rede Neuronal Externa';
            app.ClassificarButton_4 = uibutton(app.Panel_3, 'push');
            app.ClassificarButton_4.ButtonPushedFcn = createCallbackFcn(app, @ClassificarButton_4Pushed, true);
            app.ClassificarButton_4.Text = 'Classificar';
            app.Camada2Label_2 = uilabel(app.Panel_3);
            app.Camada2Label_2.Text = 'Camada 2';
            app.NmerodeNeurniosEditField_6Label = uilabel(app.Panel_3);
            app.NmerodeNeurniosEditField_6Label.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField_6 = uieditfield(app.Panel_3, 'numeric');
            app.NmerodeNeurniosEditField_6.Editable = 'off';
            app.NmerodeNeurniosEditField_6.Value = 10;
            app.Camada1Label_2 = uilabel(app.Panel_3);
            app.Camada1Label_2.Text = 'Camada 1';
            app.NmerodeNeurniosEditField_7Label = uilabel(app.Panel_3);
            app.NmerodeNeurniosEditField_7Label.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField_7 = uieditfield(app.Panel_3, 'numeric');
            app.NmerodeNeurniosEditField_7.Editable = 'off';
            app.NmerodeNeurniosEditField_7.Value = 10;
            app.TrainEditField_3Label = uilabel(app.Panel_3);
            app.TrainEditField_3Label.Text = 'Train';
            app.TrainEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.TrainEditField_3.Editable = 'off';
            app.TrainEditField_3.Value = 0.7;
            app.ValEditField_3Label = uilabel(app.Panel_3);
            app.ValEditField_3Label.Text = 'Val';
            app.ValEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.ValEditField_3.Editable = 'off';
            app.ValEditField_3.Value = 0.15;
            app.TestEditField_3Label = uilabel(app.Panel_3);
            app.TestEditField_3Label.Text = 'Test';
            app.TestEditField_3 = uieditfield(app.Panel_3, 'numeric');
            app.TestEditField_3.Editable = 'off';
            app.TestEditField_3.Value = 0.15;
            app.ClassificarButton_5 = uibutton(app.Panel_3, 'push');
            app.ClassificarButton_5.Text = 'Classificar';
            app.Label_8 = uilabel(app.Panel_3);
            app.Label_8.Text = '';
            app.ProcurarRedeExternaButton_2 = uibutton(app.Panel_3, 'push');
            app.ProcurarRedeExternaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ProcurarRedeExternaButton_2Pushed, true);
            app.ProcurarRedeExternaButton_2.Text = 'Procurar';
            app.RedeEditField_2Label = uilabel(app.Panel_3);
            app.RedeEditField_2Label.Text = 'Rede:';
            app.RedeEditField_2 = uieditfield(app.Panel_3, 'text');
            app.RedeEditField_2.Editable = 'off';
            app.ClassificarRedeExternaButton_2 = uibutton(app.Panel_3, 'push');
            app.ClassificarRedeExternaButton_2.ButtonPushedFcn = createCallbackFcn(app, @ClassificarRedeExternaButton_2Pushed, true);
            app.ClassificarRedeExternaButton_2.Text = 'Classificar';
            app.FunodeAtivaoEditField_7Label = uilabel(app.Panel_3);
            app.FunodeAtivaoEditField_7Label.Text = 'Função de Ativação';
            app.FunodeAtivaoEditField_7 = uieditfield(app.Panel_3, 'text');
            app.FunodeAtivaoEditField_7.Editable = 'off';
            app.FunodeAtivaoEditField_7.Value = 'logsig';
            app.FunodeAtivaoEditField_8Label = uilabel(app.Panel_3);
            app.FunodeAtivaoEditField_8Label.Text = 'Função de Ativação';
            app.FunodeAtivaoEditField_8 = uieditfield(app.Panel_3, 'text');
            app.FunodeAtivaoEditField_8.Editable = 'off';
            app.FunodeAtivaoEditField_8.Value = 'logsig';
            app.FunodeAtivaoSaidaEditField_2Label = uilabel(app.Panel_3);
            app.FunodeAtivaoSaidaEditField_2Label.Text = 'Função de Ativação Saida';
            app.FunodeAtivaoSaidaEditField_2 = uieditfield(app.Panel_3, 'text');
            app.FunodeAtivaoSaidaEditField_2.Editable = 'off';
            app.FunodeAtivaoSaidaEditField_2.Value = 'tansig';
            app.FunodeTreinoEditField_2Label = uilabel(app.Panel_3);
            app.FunodeTreinoEditField_2Label.Text = 'Função de Treino';
            app.FunodeTreinoEditField_2 = uieditfield(app.Panel_3, 'text');
            app.FunodeTreinoEditField_2.Editable = 'off';
            app.FunodeTreinoEditField_2.Value = 'trainlm';
            app.Panel_4 = uipanel(app.DatasetTab);
            app.ResultadoLabel_2 = uilabel(app.Panel_4);
            app.ResultadoLabel_2.Text = 'Resultado';
            app.PrecisoEditFieldLabel = uilabel(app.Panel_4);
            app.PrecisoEditFieldLabel.Text = 'Precisão:';
            app.PrecisoEditField = uieditfield(app.Panel_4, 'numeric');
            app.PrecisoEditField.Editable = 'off';
            app.TreinarTab = uitab(app.TabGroup);
            app.TreinarTab.Title = 'Treinar';
            app.Separador = uipanel(app.TreinarTab);
            app.Separador.Title = 'Arquitetura da Rede';
            app.FunodeAtivaoDropDownLabel = uilabel(app.Separador);
            app.FunodeAtivaoDropDownLabel.Text = 'Função de Ativação';
            app.FunodeAtivaoDropDown = uidropdown(app.Separador);
            app.FunodeAtivaoDropDown.Items = {'logsig', 'purelin', 'tansig'};
            app.FunodeAtivaoDropDown.Value = 'logsig';
            app.NmerodeCamadasEditFieldLabel = uilabel(app.Separador);
            app.NmerodeCamadasEditFieldLabel.Text = 'Número de Camadas';
            app.NmerodeCamadasEditField = uieditfield(app.Separador, 'numeric');
            app.NmerodeCamadasEditField.Limits = [0 10];
            app.NmerodeCamadasEditField.ValueChangedFcn = createCallbackFcn(app, @NmerodeCamadasEditFieldValueChanged, true);
            app.CamadaDropDownLabel = uilabel(app.Separador);
            app.CamadaDropDownLabel.Text = 'Camada:';
            app.CamadaDropDown = uidropdown(app.Separador);
            app.CamadaDropDown.Items = {};
            app.CamadaDropDown.ValueChangedFcn = createCallbackFcn(app, @CamadaDropDownValueChanged, true);
            app.CamadaDropDown.Value = {};
            app.NmerodeNeurniosEditFieldLabel = uilabel(app.Separador);
            app.NmerodeNeurniosEditFieldLabel.Text = 'Número de Neurónios';
            app.NmerodeNeurniosEditField = uieditfield(app.Separador, 'numeric');
            app.FunodeAtivaoSaidaLabel = uilabel(app.Separador);
            app.FunodeAtivaoSaidaLabel.Text = 'Função de Ativação Saida';
            app.FunodeAtivaoSaidaDropDown = uidropdown(app.Separador);
            app.FunodeAtivaoSaidaDropDown.Items = {'logsig', 'purelin', 'tansig'};
            app.FunodeAtivaoSaidaDropDown.Value = 'logsig';
            app.TrainEditFieldLabel = uilabel(app.Separador);
            app.TrainEditFieldLabel.Text = 'Train';
            app.TrainEditField = uieditfield(app.Separador, 'numeric');
            app.ValEditFieldLabel = uilabel(app.Separador);
            app.ValEditFieldLabel.Text = 'Val';
            app.ValEditField = uieditfield(app.Separador, 'numeric');
            app.TestEditFieldLabel = uilabel(app.Separador);
            app.TestEditFieldLabel.Text = 'Test';
            app.TestEditField = uieditfield(app.Separador, 'numeric');
            app.AtribuircamadaButton = uibutton(app.Separador, 'push');
            app.AtribuircamadaButton.ButtonPushedFcn = createCallbackFcn(app, @AtribuircamadaButtonPushed, true);
            app.AtribuircamadaButton.Text = {'Atribuir à camada'; ''};
            app.FunodeDivisoLabel = uilabel(app.Separador);
            app.FunodeDivisoLabel.Text = 'Função de Treino';
            app.FunodetreinoButtonGroup = uidropdown(app.Separador);
            app.FunodetreinoButtonGroup.Items = {'trainlm', 'traingd', 'trainbfg'};
            app.FunodetreinoButtonGroup.Value = 'trainlm';
            app.Label_2 = uilabel(app.Separador);
            app.Label_2.Text = '';
            app.DatasetdeentradaPanel = uipanel(app.TreinarTab);
            app.DatasetdeentradaPanel.Title = 'Dataset de entrada';
            app.PastadoDataSetLabel = uilabel(app.DatasetdeentradaPanel);
            app.PastadoDataSetLabel.Text = 'Pasta do DataSet:';
            app.PastadoDataSetEditField = uieditfield(app.DatasetdeentradaPanel, 'text');
            app.PastadoDataSetEditField.Editable = 'off';
            app.ProcurarButton = uibutton(app.DatasetdeentradaPanel, 'push');
            app.ProcurarButton.ButtonPushedFcn = createCallbackFcn(app, @ProcurarButtonPushed, true);
            app.ProcurarButton.Text = 'Procurar';
            app.CriarTreinarRedeButton = uibutton(app.TreinarTab, 'push');
            app.CriarTreinarRedeButton.ButtonPushedFcn = createCallbackFcn(app, @CriarTreinarRedeButtonPushed, true);
            app.CriarTreinarRedeButton.Text = 'Criar/Treinar Rede';
            app.RedeNeuronalLabel = uilabel(app.TreinarTab);
            app.RedeNeuronalLabel.Text = ' Rede Neuronal';
            app.AutoresTab = uitab(app.TabGroup);
            app.AutoresTab.Title = 'Autores';
            app.Image = uiimage(app.AutoresTab);
            app.Image.ImageSource = 'ISEClogo.png';
            app.NunoDomingues2020109910Label = uilabel(app.AutoresTab);
            app.NunoDomingues2020109910Label.Text = 'Nuno Domingues - 2020109910 ';
            app.MiguelNeves2020146521Label = uilabel(app.AutoresTab);
            app.MiguelNeves2020146521Label.Text = 'Miguel Neves - 2020146521';
            % Show the figure after all components are created
            app.CRRedeNeuronalUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CRRedeNeuronalUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CRRedeNeuronalUIFigure)
        end
    end
end